classdef apptest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.Button
        BoxEditField       matlab.ui.control.NumericEditField
        BoxEditFieldLabel  matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        TextAreaLabel      matlab.ui.control.Label
        PickandSortButton  matlab.ui.control.Button
        STOPButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        value double;
        robot;
        
    end
    
    methods (Access = private)
        
        function ItemFunc(r, Q1, Q2, n, vert, Item,m, vertItem, Item2)

            traj = jtraj(Q1,Q2,n);

            for i = 1:n
                pause(0.02);
                r.model.animate(traj(i, :));
                drawnow();
                switch m
                    case 0
                    case 1
                        EndEff = r.model.fkine(r.model.getpos()).T;
                        updatedVert = (EndEff*[vert,ones(size(vert,1),1)]')';
                        Item.Vertices = updatedVert(:,1:3);
                        drawnow();
                    case 2
                        EndEffItem1 = r.model.fkine(r.model.getpos()).T ;
                        updatedVertItem1 = (EndEffItem1*[vertItem,ones(size(vertItem,1),1)]')';
                        Item2.Vertices = updatedVertItem1(:,1:3);
                        drawnow();
                    case 3
                        EndEff = r.model.fkine(r.model.getpos()).T;
                        updatedVert = (EndEff*[vert,ones(size(vert,1),1)]')';
                        Item.Vertices = updatedVert(:,1:3);
                        drawnow();
                        EndEffItem1 = r.model.fkine(r.model.getpos()).T * transl(0.15,0.1,0.1);
                        updatedVertItem1 = (EndEffItem1*[vertItem,ones(size(vertItem,1),1)]')';
                        Item2.Vertices = updatedVertItem1(:,1:3);
                        drawnow();

                 end

             end

          end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = Kr10;

            [f,vert] = plyread('Box.PLY','tri');
            BoxesL = [0.8 -0.023 0.93;        0 0.77 0.93;        -0.8 -0.023 0.93];

            for i=1:3
                Box(i) = trisurf(f,vert(:,1)+BoxesL(i,1),vert(:,2)+BoxesL(i,2),vert(:,3)+BoxesL(i,3));
                BoxSLoc(:,:,i) = transl(BoxesL(i,1),BoxesL(i,2),BoxesL(i,3));
            end


        end

        % Value changed function: BoxEditField
        function BoxEditFieldValueChanged(app, event)

            app.value = app.BoxEditField.Value;

            app.ItemFunc(app.robot, OriginalQ, QFunc1, 100, vert, Box(1), app.value)

            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            q0 = [0 0 0 0 0 0 ]
            q1 = [1 1 1 1 1 1 ]
            qmat = jtraj(q0,q1,50)
            for i= 1:50
                app.robot.model.animate(qmat(i,:))
                drawnow()
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.Text = 'STOP';
            app.PickandSortButton = uibutton(app.UIFigure, 'push');
            app.PickandSortButton.Text = 'Pick and Sort';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'List of Boxes:'; 'Box 1, Games and Entertainment'; ''; 'Box 2, Schools and projects Item'; ''; 'Box 3, Randoms'};
            app.BoxEditFieldLabel = uilabel(app.UIFigure);
            app.BoxEditFieldLabel.Text = 'Box';
            app.BoxEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoxEditField.ValueChangedFcn = createCallbackFcn(app, @BoxEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end